classdef parking_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        background            matlab.ui.control.Image
        Label                 matlab.ui.control.Label
        EditField_2Label      matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        Label_5               matlab.ui.control.Label
        Label_7               matlab.ui.control.Label
        Label_6               matlab.ui.control.Label
        Label_11              matlab.ui.control.Label
        Label_12              matlab.ui.control.Label
        Label_22              matlab.ui.control.Label
        ProcessTextAreaLabel  matlab.ui.control.Label
        bgset                 matlab.ui.control.Image
        bgreset               matlab.ui.control.Image
        bgclose               matlab.ui.control.Image
        e50                   matlab.ui.control.Image
        leftLine              matlab.ui.control.Image
        leftLine_2            matlab.ui.control.Image
        steeringAngle         matlab.ui.control.NumericEditField
        speed                 matlab.ui.control.NumericEditField
        accY                  matlab.ui.control.NumericEditField
        accX                  matlab.ui.control.NumericEditField
        degLabel              matlab.ui.control.Label
        kmhLabel              matlab.ui.control.Label
        gLabel                matlab.ui.control.Label
        gLabel_2              matlab.ui.control.Label
        degLabel_2            matlab.ui.control.Label
        mLabel                matlab.ui.control.Label
        mLabel_2              matlab.ui.control.Label
        errorYaw              matlab.ui.control.NumericEditField
        errorX                matlab.ui.control.NumericEditField
        errorY                matlab.ui.control.NumericEditField
        mLabel_3              matlab.ui.control.Label
        lengthSlot            matlab.ui.control.NumericEditField
        timer                 matlab.ui.control.NumericEditField
        sLabel                matlab.ui.control.Label
        indRec                matlab.ui.control.Lamp
        distance              matlab.ui.control.NumericEditField
        mLabel_4              matlab.ui.control.Label
        indDistance           matlab.ui.control.Lamp
        name                  matlab.ui.control.EditField
        Label_25              matlab.ui.control.Label
        machine               matlab.ui.control.StateButton
        notice                matlab.ui.control.TextArea
        set                   matlab.ui.control.Button
        reset                 matlab.ui.control.Button
        exit                  matlab.ui.control.Button
        huTimeScore           matlab.ui.control.NumericEditField
        huAccScore            matlab.ui.control.NumericEditField
        huSafeScore           matlab.ui.control.NumericEditField
        huComScore            matlab.ui.control.NumericEditField
        huRotScore            matlab.ui.control.NumericEditField
        huScore               matlab.ui.control.NumericEditField
        maTimeScore           matlab.ui.control.NumericEditField
        maAccScore            matlab.ui.control.NumericEditField
        maSafeScore           matlab.ui.control.NumericEditField
        maComScore            matlab.ui.control.NumericEditField
        maRotScore            matlab.ui.control.NumericEditField
        maScore               matlab.ui.control.NumericEditField
        pkResult              matlab.ui.control.Label
        crash                 matlab.ui.control.EditField
        StartButton           matlab.ui.control.Button
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        trajectory            matlab.ui.control.UIAxes
        Image7                matlab.ui.control.Image
        pkResult_2            matlab.ui.control.Label
        pkResult_3            matlab.ui.control.Label
        Label_26              matlab.ui.control.Label
        vsLabel               matlab.ui.control.Label
        vsLabel_2             matlab.ui.control.Label
        Label_27              matlab.ui.control.Label
        Label_28              matlab.ui.control.Label
        vsLabel_3             matlab.ui.control.Label
        vsLabel_4             matlab.ui.control.Label
        Label_29              matlab.ui.control.Label
        vsLabel_5             matlab.ui.control.Label
        Label_30              matlab.ui.control.Label
        vsLabel_6             matlab.ui.control.Label
        Label_31              matlab.ui.control.Label
        Image7_2              matlab.ui.control.Image
        mLabel_5              matlab.ui.control.Label
        minDistance           matlab.ui.control.NumericEditField
        Label_32              matlab.ui.control.Label
    end

    
    methods (Access = private)
        function SetGUI(app)
            % get the parameters from the input
            global machine;
            global name;
            
            machine = app.machine.Value;
            if machine
                app.name.Value = '机器';
            end
            name = app.name.Value;
        end
        
        function ExitGUI(app)
            % exit this app
            global exit
            
            rosshutdown;
            %             rmpath('Callbacks');
            %             rmpath('Rank');
            setlog(app, '正在退出......');
            exit = 1;
            
            close(gcf);
        end
        
        function ResetGUI(app)   %need to be changed (score display, timer)
            global stop;
            global flag_loop2;
            global PubArrayText;
            global flag_pk;
            global last_Ref;
            global machine;
            
            machine = 1;
            stop = 1;
            flag_loop2 = 0;
            flag_pk = 0;
            PubArrayText = sprintf('%s\n','');
            last_Ref = zeros(12,2);
            
            %             InitTrajectory(app);
            
            app.set.Text = '配置';
            app.timer.Value = 0;
            app.notice.Value = PubArrayText;
            
            set([app.steeringAngle, app.speed, app.accX, app.accY,...
                app.lengthSlot, app.errorX, app.errorY, app.errorYaw, ...
                app.huTimeScore, app.huAccScore, app.huSafeScore, app.huComScore, app.huRotScore, app.huScore ...
                app.maTimeScore, app.maAccScore, app.maSafeScore, app.maComScore, app.maRotScore, app.maScore, ...
                app.minDistance, app.distance, app.timer],...
                'Value',0);
            
            set([app.huTimeScore, app.huAccScore, app.huSafeScore, app.huComScore, app.huRotScore, app.huScore], 'BackgroundColor', [0.87,0.92,0.97]);
            set([app.indDistance, app.indRec], 'Color', [0.75 0.91 0.62]);
            
            app.pkResult.Visible = 0;
        end
        
        function StartStop(app)
            global stop;
            global init_flag;
            
            stop = ~stop;
            if ~stop
                SetGUI(app);
                app.set.Text = '正在泊车';
                setlog(app, '泊车开始。');
                setlog(app, datestr(now));
                app.minDistance.Value = 0;
                init_flag = 0;
            else
                app.set.Text = '配置';
                setlog(app, '泊车结束。');
                setlog(app, datestr(now));
            end
        end
        
        %         function SteeringAngleCallback(app, ~, message, buffer)
        %             buffer.push_back([message.Header.Stamp.seconds, -message.Angle]);
        %         end
        %
        %         function Vehicle_pose2DCallback(app, ~, message, buffer)
        %             LocalX = message.Pose.Pose.Position.X;
        %             LocalY = message.Pose.Pose.Position.Y;
        %             q = [message.Pose.Pose.Orientation.W message.Pose.Pose.Orientation.X ...
        %                 message.Pose.Pose.Orientation.Y message.Pose.Pose.Orientation.Z];
        %             EulAngle = quat2eul(q);
        %
        %             buffer.push_back([message.Header.Stamp.seconds,LocalX,LocalY,EulAngle(1)])
        %         end
        
        function [p_fl, p_fr, p_rr, p_rl, Rec_l] = calpp(~, R1, R2, O1, O2, width)
            VecL = [R1(1)-O1(1), R1(2)-O1(2)];
            VecObs = [O2(1)-O1(1), O2(2)-O1(2)];
            VecRef = [R2(1)-R1(1), R2(2)-R1(2)];
            theta = atan2(VecL(2), VecL(1));
            Flag = [dot(VecL, VecObs) <= 0, dot(-VecL, VecRef) <= 0]; % 1-obtuse angle, 0-acute angle
            if isequal(Flag, [1, 1])
                Rec_l = norm(VecL);
                T = [cos(theta), -sin(theta), O1(1); sin(theta), cos(theta), O1(2); 0, 0, 1];
                p_fl = [R1(1); R1(2); 1];
                p_fr = T*[Rec_l; -width; 1];
                p_rr = T*[0; -width; 1];
                p_rl = [O1(1); O1(2); 1];
            elseif isequal(Flag, [1, 0])
                Rec_l = norm(VecL) - dot(-VecL, VecRef)/norm(VecL);
                T = [cos(theta), -sin(theta), O1(1); sin(theta), cos(theta), O1(2); 0, 0, 1];
                p_fl = T*[Rec_l; 0; 1];
                p_fr = T*[Rec_l; -width; 1];
                p_rr = T*[0; -width; 1];
                p_rl = [O1(1); O1(2); 1];
            elseif isequal(Flag, [0, 0])
                Rec_l = norm(VecL) - (dot(VecL, VecObs) + dot(-VecL, VecRef))/norm(VecL);
                Obsw = abs(det([VecL; VecObs]))/norm(VecL);
                T = [cos(theta), -sin(theta), O2(1); sin(theta), cos(theta), O2(2); 0, 0, 1];
                p_fl = T*[Rec_l; Obsw; 1];
                p_fr = T*[Rec_l; Obsw-width; 1];
                p_rr = T*[0; Obsw-width; 1];
                p_rl = T*[0; Obsw; 1];
            else
                Rec_l = norm(VecL) - dot(VecL, VecObs)/norm(VecL);
                T = [cos(theta), -sin(theta), R1(1); sin(theta), cos(theta), R1(2); 0, 0, 1];
                p_fl = [R1(1); R1(2); 1];
                p_fr = T*[0; -width; 1];
                p_rr = T*[-Rec_l; -width; 1];
                p_rl = T*[-Rec_l; 0; 1];
            end
        end
        
        %         function imuCallback(app, ~, message, buffer)
        %             Ax = message.LinearAcceleration.X;
        %             Ay = message.LinearAcceleration.Y;
        %             buffer.push_back([message.Header.Stamp.seconds, Ax, Ay])
        %         end
        
        %         function parkingslotCallback(app, ~, message, buffer)
        %
        %             RefPoseX = message.RefPose.X;
        %             RefPoseY = message.RefPose.Y;
        %             RefPoseTheta = message.RefPose.Theta;
        %             RefExtend_X = message.RefExtendX;
        %             RefExtend_Y = message.RefExtendY;
        %
        %             T1 = [cos(RefPoseTheta), -sin(RefPoseTheta), RefPoseX; sin(RefPoseTheta), cos(RefPoseTheta), RefPoseY; 0, 0, 1];
        %             RefPose1 = [RefPoseX;RefPoseY;1];
        %             b1 = [ 0; RefExtend_Y; 1];
        %             c1 = [ RefExtend_X;0;1];
        %             d1 = [ RefExtend_X;RefExtend_Y; 1];
        %             RefPose2 = T1*b1;
        %             RefPose3 = T1*c1;
        %             RefPose4 = T1*d1;
        %
        %             ObstaclePoseX = message.ObstaclePose.X;
        %             ObstaclePoseY = message.ObstaclePose.Y;
        %             ObstacleTheta = message.ObstaclePose.Theta;
        %             ObstacleExtend_X = message.ObstacleExtendX;
        %             ObstacleExtend_Y = message.ObstacleExtendY;
        %             T2 = [cos(ObstacleTheta), -sin(ObstacleTheta), ObstaclePoseX; sin(ObstacleTheta), cos(ObstacleTheta), ObstaclePoseY; 0, 0, 1];
        %             ObstaclePose1 = [ObstaclePoseX,ObstaclePoseY];
        %             b2 = [ 0; ObstacleExtend_Y; 1];
        %             c2 = [ ObstacleExtend_X;0;1];
        %             d2 = [ ObstacleExtend_X;ObstacleExtend_Y; 1];
        %             ObstaclePose2 = T2*b2;
        %             ObstaclePose3 = T2*c2;
        %             ObstaclePose4 = T2*d2;
        %
        %             buffer.push_back([message.Header.Stamp.seconds,RefPose1(1),RefPose1(2), ...
        %                 RefPose2(1),RefPose2(2),RefPose3(1),RefPose3(2),RefPose4(1),RefPose4(2), ...
        %                 ObstaclePose1(1), ObstaclePose1(2),ObstaclePose2(1), ObstaclePose2(2), ...
        %                 ObstaclePose3(1), ObstaclePose3(2),ObstaclePose4(1), ObstaclePose4(2), ...
        %                 RefPoseTheta])
        %         end
        
        function setlog(app, str)
            string = sprintf('%s\n',str);
            global PubArrayText
            PubArrayText = horzcat(string, PubArrayText);
            app.notice.Value = PubArrayText;
        end
        
        %         function velometerCallback(app, ~, message, buffer)
        %             Vx = message.Twist.Linear.X;
        %             Vy = message.Twist.Linear.Y;
        %             Speed=sqrt(Vx*Vx+Vy*Vy);
        %             buffer.push_back([message.Header.Stamp.seconds, Speed]);
        %         end
        
        
        function InitTrajectory(app)
            global imBackground;
            global imCar;
            global imRef;
            global imObs;
            global map;
            global imTmp;
            
            % hide the axis of axes
            app.trajectory.set('Xlim',[0 1000],'YLim',[0 500]);   % coordinate with the picture
            axis(app.trajectory,'off');
            % Load Image
            [imBackground, map] = imread('icon/grey.png');
            imCar = imread('icon/car.png');
            imRef = imread('icon/block.png');
            imObs = imread('icon/block.png');
            imTmp = ImageTrans(app, 50, 50, 0/180*pi);
            imshow(imTmp, map, 'Parent',app.trajectory);
            
            %             axes(app.trajectory);
            %             cla(app.trajectory);
            
            %             hold on
            %             set(app.trajectory, 'UserData', [patch([0 0 0 0],[0 0 0 0],'k'), patch([0 0 0 0],[0 0 0 0],'k'), ... fill
            %                 line([0 0],[0 0],'Color','red','LineWidth',0.5,'LineStyle','-'), ... target
            %                 line([0 0],[0 0],'Color','green','LineWidth',0.5,'LineStyle','-'), ... parking slot
            %                 line([0 0],[0 0],'Color','black','LineWidth',0.5,'LineStyle','-'), ... obstacle
            %                 line([0 0],[0 0],'Color','black','LineWidth',0.5,'LineStyle','-'), ...
            %                 line([0 0],[0 0],'Color','blue','LineWidth',0.5,'LineStyle','-')]);   % vehicle
            %             hold off
            %             set(app.trajectory,'XLim',[0 16],'YLim',[-4 1]);
        end
        
        function im = ImageTrans(app, moveXC, moveYC, rotAngleC, varargin)
            global imBackground;
            global imCar;
            global imRef;
            global imObs;
            global imTmp;
            global fixP;
            
            if ~isempty(varargin)
                im = imBackground;
                [rShow, cShow] = size(im);
                moveXR = varargin{1};
                moveYR = varargin{2};
                rotAngleR = varargin{3};
                moveXO = varargin{4};
                moveYO = varargin{5};
                rotAngleO = varargin{6};
                
                [rObj, cObj] = size(imCar);
                transM=[cos(rotAngleC), sin(rotAngleC), moveXC;...
                    -sin(rotAngleC), cos(rotAngleC), moveYC; ...
                    0, 0, 1];
                tmpMatrix = [kron((1:cObj),ones(1,rObj)); ...
                    kron(ones(1,cObj),(1:rObj)); ...
                    ones(1, cObj * rObj)];
                tmpResult = fix(transM * tmpMatrix);
                tmpJudge = (tmpResult(1,:)>=1) .* (tmpResult(1,:)<=cShow) .* (tmpResult(2,:)>=1) .* (tmpResult(2,:)<=rShow);
                tmpJudge(2,:) = 1:size(tmpJudge,2);
                objNum = tmpJudge(2, tmpJudge(1,:) == 1);
                tmpResult = tmpResult .* tmpJudge(1,:);
                tmpResult(:,tmpResult(1,:) == 0) = [];
                imNum = (tmpResult(1,:) - 1) * rShow + tmpResult(2,:);
                im(imNum) = imCar(objNum);
                
                [rObj, cObj] = size(imRef);
                transM=[cos(rotAngleR), sin(rotAngleR), moveXR;...
                    -sin(rotAngleR), cos(rotAngleR), moveYR; ...
                    0, 0, 1];
                tmpMatrix = [kron((1:cObj),ones(1,rObj)); ...
                    kron(ones(1,cObj),(1:rObj)); ...
                    ones(1, cObj * rObj)];
                tmpResult = fix(transM * tmpMatrix);
                tmpJudge = (tmpResult(1,:)>=1) .* (tmpResult(1,:)<=cShow) .* (tmpResult(2,:)>=1) .* (tmpResult(2,:)<=rShow);
                tmpJudge(2,:) = 1:size(tmpJudge,2);
                objNum = tmpJudge(2, tmpJudge(1,:) == 1);
                tmpResult = tmpResult .* tmpJudge(1,:);
                tmpResult(:,tmpResult(1,:) == 0) = [];
                imNum = (tmpResult(1,:) - 1) * rShow + tmpResult(2,:);
                im(imNum) = imRef(objNum);
                
                [rObj, cObj] = size(imObs);
                transM=[cos(rotAngleO), sin(rotAngleO), moveXO;...
                    -sin(rotAngleO), cos(rotAngleO), moveYO; ...
                    0, 0, 1];
                tmpMatrix = [kron((1:cObj),ones(1,rObj)); ...
                    kron(ones(1,cObj),(1:rObj)); ...
                    ones(1, cObj * rObj)];
                tmpResult = fix(transM * tmpMatrix);
                tmpJudge = (tmpResult(1,:)>=1) .* (tmpResult(1,:)<=cShow) .* (tmpResult(2,:)>=1) .* (tmpResult(2,:)<=rShow);
                tmpJudge(2,:) = 1:size(tmpJudge,2);
                objNum = tmpJudge(2, tmpJudge(1,:) == 1);
                tmpResult = tmpResult .* tmpJudge(1,:);
                tmpResult(:,tmpResult(1,:) == 0) = [];
                imNum = (tmpResult(1,:) - 1) * rShow + tmpResult(2,:);
                im(imNum) = imObs(objNum);
            else
                im = imBackground;
                [rShow, cShow] = size(im);
                [rObj, cObj] = size(imCar);
                transM=[cos(rotAngleC), sin(rotAngleC), moveXC;...
                    -sin(rotAngleC), cos(rotAngleC), moveYC; ...
                    0, 0, 1];
                tmpMatrix = [kron((1:cObj),ones(1,rObj)); ...
                    kron(ones(1,cObj),(1:rObj)); ...
                    ones(1, cObj * rObj)];
                tmpResult = fix(transM * tmpMatrix);
                tmpJudge = (tmpResult(1,:)>=1) .* (tmpResult(1,:)<=cShow) .* (tmpResult(2,:)>=1) .* (tmpResult(2,:)<=rShow);
                tmpJudge(2,:) = 1:size(tmpJudge,2);
                objNum = tmpJudge(2, tmpJudge(1,:) == 1);
                tmpResult = tmpResult .* tmpJudge(1,:);
                tmpResult(:,tmpResult(1,:) == 0) = [];
                imNum = (tmpResult(1,:) - 1) * rShow + tmpResult(2,:);
                im(imNum) = imCar(objNum);
            end
        end
        
        function flag = parkingJudge(app, last_Ref)
            flag = [0 0];
            Obs_fl = last_Ref(:,9);
            Ref_rl = last_Ref(:,8);
            Vector = Ref_rl - Obs_fl;
            Theta = atan2(Vector(2), Vector(1));
            VehiclePose = [last_Ref(:,13:20); ones(1,8)];
            TransMartrix = [cos(Theta) -sin(Theta) Obs_fl(1); ...
                            sin(Theta) cos(Theta) Obs_fl(2); ...
                            0          0          1];
            VehiclePoseInObs = TransMartrix \ VehiclePose;
            if all(VehiclePoseInObs(2,:) <= 0.3)
                flag(1) = 1;
            end
%             if max(VehiclePoseInObs(2,:)) > 0
%                 flag(2) = max(VehiclePoseInObs(2,:)) * 5 / 0.3;
%             end
            
%             last_Ref = [p_fl(1:2) p_fr(1:2) p_rr(1:2) p_rl(1:2) ...
%                 Ref_fl Ref_fr(1:2) Ref_rr(1:2) Ref_rl ...
%                 Obs_fl Obs_fr(1:2) Obs_rr(1:2) Obs_rl ...
%                 V1G(1:2) V2G(1:2) V3G(1:2) V4G(1:2) ...
%                 V5G(1:2) V6G(1:2) V7G(1:2) V8G(1:2)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function parking_GUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to parking_GUI (see VARARGIN)
            
            addpath('Callbacks');
            addpath('Rank');
            addpath('icon');
            global PubArrayText % show interaction message
            global machine      % is machine?
            global fixP;
            
            fixP = [150;...  vehicle display, offset in y direction
                180];  
            machine = 1;
            
            rosshutdown;
            rosinit;
            InitTrajectory(app);
            
            PubArrayText = sprintf('%s\n%s\n','Wait for input','Or you can click exit');
            app.notice.Value = PubArrayText;
            
            % Choose default command line output for parking_GUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            
            % --- Outputs from this function are returned to the command line.
            global stop;   % stop parking
            global exit;  % close GUI
            global vehiclePose;
            global last_Ref;
            global xError;
            global yError;
            global HeadingAngleError;
            global risk_score;
            global init_flag;
            global imBackground;
            global imCar;
            global imRef;
            global imObs;
            global map;
            
            risk_score = 0;
            stop = 1;
            exit = 0;
            last_Ref = zeros(2,20);
            init_flag = 0;
            p_width = 2;
            parkingtime = 0; %泊车时间
            minDis = 100;  %最小安全距离
            
            %整车参数
            vWidth = 1.551; %车宽
            vLength = 3.569; %车长
            rOverhang = 0.544; %后悬
            fOverhang = 0.72; %前悬
            wheelBase = 2.305;%轴距
            fWheelTrack = 1.324;%前轮距
            rWheelTrack = 1.292;%后轮距
            fVWidth = 0.791; %对应八边形f边
            rVWidth = 0.821; %对应八边形c边
            
            V1L = [3.026;0.3955;1];V2L=[3.026;-0.3955;1];V3L=[2.646;-0.7755;1]; V4L=[-0.384;-0.7755;1];
            V5L = [-0.544;-0.4105;1]; V6L = [-0.544;0.4105;1]; V7L = [-0.384;0.7755;1]; V8L = [2.646;0.7755;1];
            vLR = [-0.544;0.7755;1];
            
            
            % scale   m / pixel
            xScale = vLength / size(imCar,2);
            yScale = vWidth / size(imCar,1);
            
            %% rossubscriber initialization
            angle = myvector(2);
            sub_steering_angle = rossubscriber('/steering_angle_deg', 'apa_msgs/SteeringAngleStamped',{@SteeringAngleCallback, angle}, 'BufferSize', 5);
            angleSub = rossubscriber('/steering_angle_deg', 'apa_msgs/SteeringAngleStamped');
            
            VehicleSpeed = myvector(2);
            velometer = rossubscriber('/velometer/base_link_local', 'geometry_msgs/TwistStamped',{@velometerCallback,VehicleSpeed});
            vehicleSpeedSub = rossubscriber('/velometer/base_link_local', 'geometry_msgs/TwistStamped');
            
            LocalA = myvector(3);
            imu = rossubscriber('/imu/data', 'sensor_msgs/Imu',{@imuCallback,LocalA});
            localASub = rossubscriber('/imu/data', 'sensor_msgs/Imu');
            
            %       {time,Ref1,Ref2,Ref3,Ref4,Obstacle1,Obstacle2,Obstacle3,Obstacle4,refposetheta}
            parkingSlot = myvector(18);
            parking_slot = rossubscriber('/parking_slot_info', 'apa_msgs/SlotInfoStamped',{@parkingslotCallback,parkingSlot}, 'BufferSize', 1);
            parkSub = rossubscriber('/parking_slot_info', 'apa_msgs/SlotInfoStamped');
            
            %       {time,localx,localy,yaw}
            vehiclePose = myvector(4);
            Vehicle_pose2D = rossubscriber('/odometer/local_map/base_link', 'nav_msgs/Odometry',{@Vehicle_pose2DCallback,vehiclePose}, 'BufferSize', 1);
            vehicleSub = rossubscriber('/odometer/local_map/base_link', 'nav_msgs/Odometry');
            
            % P-8, R-7, N-6, D-5
            gearSub = rossubscriber('/shift_position', 'apa_msgs/IntStamped');
            
            % start-1, stop-0;
            enableSub = rossubscriber('/time_enable', 'apa_msgs/IntStamped');
            
            pause(1);
            setlog(app, '话题订阅成功！');
            myCount = 0;    % show parking slot only once
            %% main loop
            while(~exit)
                %     tic
                try
                    if (gearSub.LatestMessage.Value ~= 8 && stop == 1 && ~machine) || ...
                            (gearSub.LatestMessage.Value == 8 && stop == 0 && ~machine) || ...
                            (enableSub.LatestMessage.Value == 1 && stop == 1 && machine) || ...
                            (enableSub.LatestMessage.Value == 0 && stop == 0 && machine)
                        StartStop(app);
                    end
                catch
                end
                
                pause(0.05);
                %% when the parking process starts
                if ~(stop||init_flag)
                    %创建订阅器与选择显示模块
                    angle.clear();
                    VehicleSpeed.clear();
                    LocalA.clear();
                    
                    %       {time,Ref1,Ref2,Ref3,Ref4,Obstacle1,Obstacle2,Obstacle3,Obstacle4,refposetheta}
                    parkingSlot.clear();
                    
                    %       {time,localx,localy,yaw}
                    vehiclePose.clear();
                    
                    setlog(app, '容器已重置。');
                    
                    init_flag = 1;  % finish initialization
                    mytimer = tic;
                    app.indRec.Color = [0.91 0.61 0.61];
                    
                    myCount = 0;
                end
                
                %% when the parking process finishes
                if stop&&init_flag
                    recordnum = [mySize(angle), mySize(VehicleSpeed), mySize(LocalA), mySize(parkingSlot), mySize(vehiclePose)];
%                     if all(recordnum ~= 0)
                        % record the latest position
                        setlog(app, 'here is ok');
                        last_Ref = [p_fl(1:2) p_fr(1:2) p_rr(1:2) p_rl(1:2) ...
                            Ref_fl Ref_fr(1:2) Ref_rr(1:2) Ref_rl ...
                            Obs_fl Obs_fr(1:2) Obs_rr(1:2) Obs_rl ...
                            V1G(1:2) V2G(1:2) V3G(1:2) V4G(1:2) ...
                            V5G(1:2) V6G(1:2) V7G(1:2) V8G(1:2)];
                        
                        % judge: did the car drive into the parking lot? (1-no, 0-yes)
                        parkingFlag = parkingJudge(app, last_Ref);
%                         standardLine = Ref_rl - Obs_fl;
%                         standardLine(3) = 0;
%                         lineGroup = last_Ref(:,13:18) - Obs_fl;
%                         lineGroup(3,:) = 0;
%                         judgeMatrix = zeros(3, 6);
%                         for number = 1 : 6
%                             judgeMatrix(:, number) = cross(standardLine, lineGroup(:,number)) > 0;
%                         end
                        if parkingFlag(1)
                            %泊车时间评分
                            Time_score = T_Assessment(parkingtime);
                            
                            %姿态精度评分
                            angleError = deg2rad(HeadingAngleError);
                            acc_score = acc_Assessment(abs(xError),abs(yError),abs(angleError));
%                             acc_score = max(0, acc_score - parkingFlag(2));
                            
                            %舒适度评分
                            Acc = get_above(LocalA, recordnum(3));
                            com_score = com_Assessment(Acc(:,2),Acc(:,3));
                            
                            %原地转向时长评分
                            rot_score = rot_Assessment(get_above(VehicleSpeed, recordnum(2)),get_above(angle, recordnum(1)));
                            
                            %计算碰撞风险评分
                            risk_penalty = 0;
                            if isnan(minDis)
%                                 risk = 0;
                                risk_penalty = 5;
                            else
                                set(app.minDistance, 'Value', minDis);
                            end
                            risk=Risk(risk_score, recordnum(4));
                            risk = max(0, risk - risk_penalty);
                            % score = [weight of every element; score of every element; percent of every element]
                            score = eva(Time_score,acc_score,risk,com_score,rot_score);
                            
                            try
                                scoreList = readtable(['DataSave/成绩记录' datestr(now, 'yymmdd')]);
                            catch
                            end
                            if exist('scoreList', 'var') && ~machine
                                scoreBot = scoreList(ismissing(scoreList(:,2), '机器'),:);
                                if ~isempty(scoreBot)
%                                     [~, botScoreIndex] = max(table2array(scoreBot(:,4)));  % pick the highest score of Robot
                                    [~, botScoreIndex] = min(table2array(scoreBot(:,4)));  % pick the lowest score of Robot
%                                     botScoreArray = table2array(scoreBot(end, 4 : 9))';  % pick the latest score of Robot
                                    botScoreArray = table2array(scoreBot(botScoreIndex, 4:9))';
                                    humanScoreArray = [sum(score(:,2)); score(:,2)];
                                    redArray = [app.huScore, app.huTimeScore, app.huAccScore, ...
                                        app.huSafeScore, app.huComScore, app.huRotScore];
                                    %                                     set(redArray, {'String'}, cellfun(@(x) num2str(x, '%.2f'), ...
                                    %                                         num2cell(humanScoreArray), 'UniformOutput', false));
                                    greenArray = redArray;
                                    redArray(humanScoreArray <= botScoreArray) = [];
                                    greenArray(humanScoreArray >= botScoreArray) = [];
                                    set(redArray, 'BackgroundColor', 'red');
                                    set(greenArray, 'BackgroundColor', 'green');
                                    set([app.maScore, app.maTimeScore, app.maAccScore, ...
                                        app.maSafeScore, app.maComScore, app.maRotScore], {'Value'}, ...
                                        num2cell(botScoreArray));
                                    if humanScoreArray(1) > botScoreArray(1)
                                        set(app.pkResult, 'Text', '挑战成功', 'FontColor', [0.91,0.61,0.61], 'FontSize', 50, 'Visible', 1);
                                    elseif humanScoreArray(1) == botScoreArray(1)
                                        set(app.pkResult, 'Text', '平局', 'FontColor', [0.75,0.91,0.62], 'FontSize', 50, 'Visible', 1);
                                    else
                                        set(app.pkResult, 'Text', '挑战失败', 'FontColor', [0.75,0.91,0.62], 'FontSize', 50, 'Visible', 1);
                                    end
                                end
                            end
                        else
                            score = zeros(5,4);
                            setlog(app, '车辆未完全泊入库位中，本次泊车成绩为0分。');
                            set(app.pkResult, 'Text', '未入库位', 'FontColor', [0.91,0.61,0.61], 'FontSize', 50, 'Visible', 1);
                        end
                        
                        app.timer.Value = parkingtime;
                        if machine
                            app.maTimeScore.Value = score(1,2);
                            app.maAccScore.Value = score(2,2);
                            app.maSafeScore.Value = score(3,2);
                            app.maComScore.Value = score(4,2);
                            app.maRotScore.Value = score(5,2);
                            app.maScore.Value = sum(score(:,2));
                        else
                            app.huTimeScore.Value = score(1,2);
                            app.huAccScore.Value = score(2,2);
                            app.huSafeScore.Value = score(3,2);
                            app.huComScore.Value = score(4,2);
                            app.huRotScore.Value = score(5,2);
                            app.huScore.Value = sum(score(:,2));
                        end
                        
                        % saving data
                        if ~exist('DataSave', 'dir')
                            mkdir('DataSave');
                        end
                        saveFileName = ['DataSave/' app.name.Value '_' datestr(now,'yymmddHHMM') '.xls'];
                        setlog(app, '正在保存数据...');
                        writematrix(angle.get_above(recordnum(1)), saveFileName, 'Sheet', '!steering_angle_deg');
                        writematrix(VehicleSpeed.get_above(recordnum(2)), saveFileName, 'Sheet', '!velometer!base_link_local');
                        writematrix(LocalA.get_above(recordnum(3)), saveFileName, 'Sheet', '!imu!data');
                        writematrix(parkingSlot.get_above(recordnum(4)), saveFileName, 'Sheet', '!parking_slot_info');
                        writematrix(vehiclePose.get_above(recordnum(5)), saveFileName, 'Sheet', '!odometer!local_map!base_link');
                        
                        % saving score
                        saveFileName = ['DataSave/成绩记录' datestr(now, 'yymmdd') '.xls'];
                        if ~exist(saveFileName, 'file')
                            writetable(table({'实验时间'},{'姓名'},{'泊车时间'},...
                                {'泊车总分'},{'泊车时间分'},{'精度分'},{'安全分'},...
                                {'舒适度分'},{'原地转向分'}), saveFileName, 'WriteVariableNames',false);
                        end
                        writetable(table({datestr(now, 'yymmdd-HH-MM')}, ...
                            {app.name.Value, parkingtime, ...
                            sum(score(:, 2)), score(1,2), score(2,2), score(3,2), ...
                            score(4,2) score(5,2)}), saveFileName, 'WriteMode','Append', ...
                            'WriteVariableNames',false);
                        
                        setlog(app, '保存完毕，请继续。');
%                     end
                    if ~machine
                        machine = 1;
                    end
                    init_flag = 0;
                    app.indRec.Color = [0.75 0.91 0.62];
                    app.set.Text = '配置';
                end


                latest_angle_record = angleSub.LatestMessage;
                if isempty(latest_angle_record)
                    latest_angle_record = 111111;
                else
                    latest_angle_record = latest_angle_record.Angle;
                end
                pause(0.02);
                
                latest_A_record = localASub.LatestMessage;
                if isempty(latest_A_record)
                    latest_A_record = [111111; 111111];
                else
                    latest_A_record = [latest_A_record.LinearAcceleration.X; latest_A_record.LinearAcceleration.Y];
                end
                pause(0.02);
                
                latest_speed_record = vehicleSpeedSub.LatestMessage;
                if isempty(latest_speed_record)
                    latest_speed_record = 111111;
                else
                    latest_speed_record = sqrt(latest_speed_record.Twist.Linear.X^2 + latest_speed_record.Twist.Linear.Y^2);
                end
                pause(0.02);
                
                latest_vehiclePose_record = vehicleSub.LatestMessage;
                if isempty(latest_vehiclePose_record)
                    LocalX = 111111;
                else
                    fprintf([num2str(latest_vehiclePose_record.Header.Stamp.seconds, '%.2f') '\n']);
                    LocalX = latest_vehiclePose_record.Pose.Pose.Position.X;
                    LocalY = latest_vehiclePose_record.Pose.Pose.Position.Y;
                    q = [latest_vehiclePose_record.Pose.Pose.Orientation.W latest_vehiclePose_record.Pose.Pose.Orientation.X ...
                        latest_vehiclePose_record.Pose.Pose.Orientation.Y latest_vehiclePose_record.Pose.Pose.Orientation.Z];
                    EulAngle = quat2eul(q);
                    Yaw = EulAngle(1);
                end
                
                latest_parkingSlot_record = parkSub.LatestMessage;
                if isempty(latest_parkingSlot_record)
                    RefPose1 = 111111;
                else
                    RefPoseX = latest_parkingSlot_record.RefPose.X;
                    RefPoseY = latest_parkingSlot_record.RefPose.Y;
                    RefPoseTheta = latest_parkingSlot_record.RefPose.Theta;
                    RefExtend_X = latest_parkingSlot_record.RefExtendX;
                    RefExtend_Y = latest_parkingSlot_record.RefExtendY;
                    
                    T = [cos(RefPoseTheta), -sin(RefPoseTheta), RefPoseX; sin(RefPoseTheta), cos(RefPoseTheta), RefPoseY; 0, 0, 1];
                    RefPose1 = [RefPoseX;RefPoseY];
                    b = [ 0; RefExtend_Y; 1];
                    c = [ RefExtend_X;0;1];
                    %         d = [ RefExtend_X;RefExtend_Y; 1];
                    RefPose2 = T*b;  RefPose2(3) = [];
                    RefPose3 = T*c;  RefPose3(3) = [];
                    %         RefPose4 = T*d;  RefPose4(3) = [];
                    refPose = [RefPose2, RefPose1, RefPose3];
                    
                    ObstaclePoseX = latest_parkingSlot_record.ObstaclePose.X;
                    ObstaclePoseY = latest_parkingSlot_record.ObstaclePose.Y;
                    ObstacleTheta = latest_parkingSlot_record.ObstaclePose.Theta;
                    ObstacleExtend_X = latest_parkingSlot_record.ObstacleExtendX;
                    ObstacleExtend_Y = latest_parkingSlot_record.ObstacleExtendY;
                    T = [cos(ObstacleTheta), -sin(ObstacleTheta), ObstaclePoseX; sin(ObstacleTheta), cos(ObstacleTheta), ObstaclePoseY; 0, 0, 1];
                    ObstaclePose1 = [ObstaclePoseX;ObstaclePoseY];
                    b = [ 0; ObstacleExtend_Y; 1];
                    c = [ ObstacleExtend_X;0;1];
                    d = [ ObstacleExtend_X;ObstacleExtend_Y; 1];
                    ObstaclePose2 = T*b;  ObstaclePose2(3) = [];
                    ObstaclePose3 = T*c;  ObstaclePose3(3) = [];
                    ObstaclePose4 = T*d;  ObstaclePose4(3) = [];
                    obstaclePose = [ObstaclePose2, ObstaclePose1, ObstaclePose3,ObstaclePose4];
                end
                
                %%  Get and display message
                if exit
                    break;
                end
                % parking time
                if sum(app.indRec.Color == [0.91 0.61 0.61]) == 3
                    parkingtime = toc(mytimer);
                    app.timer.Value = parkingtime;
                end
                %方向盘转角
                app.steeringAngle.Value = latest_angle_record;
                %车速
                app.speed.Value = latest_speed_record*3.6;
                %横向加速度
                app.accX.Value = latest_A_record(2)/9.8;
                %纵向加速度
                app.accY.Value = latest_A_record(1)/9.8;
                
                
                if LocalX ~= 111111
                    
                    %% 记录当前车辆位置
                    %求从车身坐标系到全局坐标系的刚体变换矩阵
                    T = [cos(Yaw), -sin(Yaw), LocalX; sin(Yaw), cos(Yaw), LocalY; 0, 0, 1];
                    %求车辆八角点在全局坐标系下的位置
                    V1G = T*V1L; V2G = T*V2L; V3G = T*V3L; V4G = T*V4L;
                    V5G = T*V5L; V6G = T*V6L; V7G = T*V7L; V8G = T*V8L;
                    vLG = T*vLR;
                    
                    if RefPose1(1) ~= 111111
                        % Target parking position
                        VecObs = [ObstaclePose2(1) - ObstaclePose1(1), ObstaclePose2(2) - ObstaclePose1(2)];
                        Theta_Obs = atan2(VecObs(2), VecObs(1));
                        T_Obs = [cos(Theta_Obs), -sin(Theta_Obs), ObstaclePose1(1); ...
                            sin(Theta_Obs), cos(Theta_Obs), ObstaclePose1(2); 0, 0, 1];
                        Obs_fr = T_Obs * [p_width; 0; 1];
                        Obs_rr = T_Obs * [p_width; -p_width; 1];
                        Obs_fl = ObstaclePose1;
                        Obs_rl = ObstaclePose3;
                        VecRef = [RefPose2(1) - RefPose1(1), RefPose2(2) - RefPose1(2)];
                        Theta_Ref = atan2(VecRef(2), VecRef(1));
                        T_Ref = [cos(Theta_Ref), -sin(Theta_Ref), RefPose1(1); ...
                            sin(Theta_Ref), cos(Theta_Ref), RefPose1(2); 0, 0, 1];
                        Ref_rr = T_Ref * [p_width; 0; 1];
                        Ref_fr = T_Ref * [p_width; p_width; 1];
                        Ref_rl = RefPose1;
                        Ref_fl = RefPose3;
                        [p_fl, p_fr, p_rr, p_rl, p_length] = calpp(app, Ref_rl, Ref_rr, Obs_fl, Obs_fr, p_width);
                        app.lengthSlot.Value = p_length;
                        PoseTheta = atan2(p_fl(2) - p_rl(2), p_fl(1) - p_rl(1));

                        tmpObsVec = Obs_fl - Obs_rl;
                        tmpObsL = norm(tmpObsVec);
                        tmpObsTheta = atan2(tmpObsVec(2), tmpObsVec(1));
                        tmpErrorL = 125 * xScale - tmpObsL;
                        
                        im = ImageTrans(app, vLG(1)/xScale, fixP(1)-vLG(2)/yScale, Yaw, ...
                            Ref_rl(1) / xScale, fixP(1)-Ref_rl(2)/yScale, atan2(Ref_fl(2) - Ref_rl(2), Ref_fl(1) - Ref_rl(1)), ...
                            (Obs_rl(1) - tmpErrorL * cos(tmpObsTheta)) / xScale, ...
                            fixP(1)-Obs_rl(2)/yScale + tmpErrorL * sin(tmpObsTheta),...
                            atan2(Obs_fl(2) - Obs_rl(2), Obs_fl(1) - Obs_rl(1)));
                        
                        %                         if myCount >= 0
                        %                             %绘制算法目标车位  3
                        %                             set(app.trajectory.UserData(3), 'XData', [p_fl(1) p_fr(1) NaN ...
                        %                                 p_fr(1) p_rr(1) NaN p_rr(1) p_rl(1) NaN p_rl(1) p_fl(1)], ...
                        %                                 'Ydata', [p_fl(2) p_fr(2) NaN p_fr(2) p_rr(2) NaN p_rr(2) p_rl(2) NaN ...
                        %                                 p_rl(2) p_fl(2)]);
                        %
                        %
                        %                             %绘制实际泊车空间  4
                        %                             set(app.trajectory.UserData(4), 'XData', [Ref_rl(1) Ref_rr(1) NaN ...
                        %                                 Ref_rr(1) Obs_fr(1) NaN Obs_fr(1) Obs_fl(1) NaN Obs_fl(1) Ref_rl(1)], ...
                        %                                 'Ydata', [Ref_rl(2) Ref_rr(2) NaN Ref_rr(2) Obs_fr(2) NaN Obs_fr(2) Obs_fl(2) NaN ...
                        %                                 Obs_fl(2) Ref_rl(2)]);
                        %
                        %
                        %                             %                         %填充  1, 2
                        %                             %                         set(app.trajectory.UserData(1), 'XData', [Obs_fl(1), Obs_fr(1), Obs_rr(1), Obs_rl(1)], ...
                        %                             %                             'YData', [Obs_fl(2), Obs_fr(2), Obs_rr(2), Obs_rl(2)]);
                        %                             %                         set(app.trajectory.UserData(2), 'XData', [Ref_fl(1), Ref_fr(1), Ref_rr(1), Ref_rl(1)], ...
                        %                             %                             'YData', [Ref_fl(2), Ref_fr(2), Ref_rr(2), Ref_rl(2)]);
                        %
                        %                             % Obstalce
                        %                             myCount = ~myCount;
                        %                         end
                    else
                        im = ImageTrans(app, vLG(1)/xScale, fixP(1)-vLG(2)/yScale, Yaw);
                        %
                        %                         %绘制车辆模型，以长方形框表示实时位置 7
                        %                         set(app.trajectory.UserData(7), 'XData', [V1G(1) V2G(1) NaN ...
                        %                             V2G(1) V3G(1) NaN V3G(1) V4G(1) NaN ...
                        %                             V4G(1) V5G(1) NaN V5G(1) V6G(1) NaN ...
                        %                             V6G(1) V7G(1) NaN V7G(1) V8G(1) NaN ...
                        %                             V8G(1) V1G(1)], 'Ydata', [V1G(2) V2G(2) NaN ...
                        %                             V2G(2) V3G(2) NaN V3G(2) V4G(2) NaN ...
                        %                             V4G(2) V5G(2) NaN V5G(2) V6G(2) NaN ...
                        %                             V6G(2) V7G(2) NaN V7G(2) V8G(2) NaN ...
                        %                             V8G(2) V1G(2)]);
                    end
                    imshow(im, map, 'Parent', app.trajectory);
                    pause(0.05);
                    carPose = [V1G(1:2), V2G(1:2), V3G(1:2), V4G(1:2), ...
                        V5G(1:2), V6G(1:2), V7G(1:2), V8G(1:2)];
                end
                
                
                if ~stop && init_flag
                    if LocalX ~= 111111 && RefPose1(1) ~= 111111
                        %求从全局坐标系到车位坐标系的刚体变换矩阵
                        %                 % Translate
                        %                 Tt = [1 0 -p_rl(1); 0 1 -p_rl(2); 0 0 1];
                        %                 % Rotate
                        %                 Tr = [cos(PoseTheta) sin(PoseTheta) 0; -sin(PoseTheta) cos(PoseTheta) 0; 0 0 1];
                        %求车辆质心在目标车位坐标系下的位置
                        V0 = [LocalX;LocalY;1];
                        T0_rl = [cos(PoseTheta) -sin(PoseTheta) p_rl(1); sin(PoseTheta) cos(PoseTheta) p_rl(2); 0 0 1];
                        V = T0_rl \ V0; %V为车位坐标系下车辆质心位置，车位坐标系以障碍车外角点为原点
                        
                        %横向偏差
                        h = abs(V(2));
                        yError = h-0.8;  % positive -- outside
                        app.errorY.Value = yError;
                        
                        %纵向偏差
                        x_= (p_length - 3.57)/2 + 0.544; %标准纵向位置
                        xError = V(1) - x_;      % positive -- close to the reference
                        app.errorX.Value = xError;
                        
                        %航向角偏差
                        HeadingAngleError = rad2deg(Yaw-PoseTheta);  % positive -- counterclockwise
                        app.errorYaw.Value = HeadingAngleError;
                        
                        [scoreAdd, dangerDistance, collisionFlag] = Risk_Assessment(refPose, obstaclePose, carPose);
                        if dangerDistance < minDis && ~isnan(minDis)
                            minDis = dangerDistance;
                        end
                        risk_score = risk_score + scoreAdd;
                        
                        if collisionFlag == 1
                            set(app.crash, 'Value', '与前障碍物碰撞', 'Visible', 1);
                            minDis = NaN;
                        elseif collisionFlag == 2
                            set(app.crash, 'Value', '与后障碍物碰撞', 'Visible', 1);
                            minDis = NaN;
                        elseif collisionFlag == 3
                            set(app.crash, 'Value', '与路沿发生碰撞', 'Visible', 1);
                            minDis = NaN;
                        else
                            app.crash.Visible = 0;
                            app.distance.Value = dangerDistance;
                            if scoreAdd == 1
                                app.indDistance.Color = [0.91 0.61 0.61];
                            else
                                app.indDistance.Color = [0.75 0.91 0.62];
                            end
                        end
                    end
                end
                drawnow limitrate;
                %     end
                %     toc
            end
            
        end

        % Button pushed function: exit
        function exitButtonPushed(app, event)
            ExitGUI(app);
        end

        % Button pushed function: set
        function setButtonPushed(app, event)
            if strcmp(app.set.Text, '配置') 
                SetGUI(app);
                set(app.set, 'Text', '已准备');
            end
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            ResetGUI(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            StartStop(app);
        end

        % Value changed function: machine
        function machineValueChanged(app, event)
            if app.machine.Value == 1
                app.machine.Text = '机器';
                app.name.Value = '机器';
            else
                app.machine.Text = '人类';
            end
            
        end

        % Image clicked function: bgset
        function bgsetImageClicked(app, event)
            app.StartButton.Visible = 1;
            app.notice.Visible = 1;
        end

        % Image clicked function: bgreset
        function bgresetImageClicked(app, event)
            app.StartButton.Visible = 0;
            app.notice.Visible = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'parking_GUI';
            app.figure1.Tag = 'figure1';
            app.background = uiimage(app.figure1);
            app.background.ImageSource = 'background.png';
            app.Label = uilabel(app.figure1);
            app.Label.Text = '方向盘转角';
            app.EditField_2Label = uilabel(app.figure1);
            app.EditField_2Label.Text = '实时速度值';
            app.Label_2 = uilabel(app.figure1);
            app.Label_2.Text = '横向加速度';
            app.Label_3 = uilabel(app.figure1);
            app.Label_3.Text = '纵向加速度';
            app.Label_4 = uilabel(app.figure1);
            app.Label_4.Text = '航线角偏差';
            app.Label_5 = uilabel(app.figure1);
            app.Label_5.Text = '纵向偏差量';
            app.Label_7 = uilabel(app.figure1);
            app.Label_7.Text = '横向偏差量';
            app.Label_6 = uilabel(app.figure1);
            app.Label_6.Text = '库位总长度';
            app.Label_11 = uilabel(app.figure1);
            app.Label_11.Text = '泊车时间';
            app.Label_12 = uilabel(app.figure1);
            app.Label_12.Text = '到障碍物的距离';
            app.Label_22 = uilabel(app.figure1);
            app.Label_22.Text = '姓名';
            app.ProcessTextAreaLabel = uilabel(app.figure1);
            app.ProcessTextAreaLabel.Visible = 'off';
            app.ProcessTextAreaLabel.Text = 'Process';
            app.bgset = uiimage(app.figure1);
            app.bgset.ImageClickedFcn = createCallbackFcn(app, @bgsetImageClicked, true);
            app.bgset.ImageSource = 'pink.png';
            app.bgreset = uiimage(app.figure1);
            app.bgreset.ImageClickedFcn = createCallbackFcn(app, @bgresetImageClicked, true);
            app.bgreset.ImageSource = 'lightblue.png';
            app.bgclose = uiimage(app.figure1);
            app.bgclose.ImageSource = 'lightred.png';
            app.e50 = uiimage(app.figure1);
            app.e50.ImageSource = 'e50.png';
            app.leftLine = uiimage(app.figure1);
            app.leftLine.ImageSource = '引线.svg';
            app.leftLine_2 = uiimage(app.figure1);
            app.leftLine_2.ImageSource = '引线右.svg';
            app.steeringAngle = uieditfield(app.figure1, 'numeric');
            app.steeringAngle.ValueDisplayFormat = '%.2f';
            app.speed = uieditfield(app.figure1, 'numeric');
            app.speed.ValueDisplayFormat = '%.2f';
            app.accY = uieditfield(app.figure1, 'numeric');
            app.accY.ValueDisplayFormat = '%.2f';
            app.accX = uieditfield(app.figure1, 'numeric');
            app.accX.ValueDisplayFormat = '%.2f';
            app.degLabel = uilabel(app.figure1);
            app.degLabel.Text = 'deg';
            app.kmhLabel = uilabel(app.figure1);
            app.kmhLabel.Text = 'km/h';
            app.gLabel = uilabel(app.figure1);
            app.gLabel.Text = 'g';
            app.gLabel_2 = uilabel(app.figure1);
            app.gLabel_2.Text = 'g';
            app.degLabel_2 = uilabel(app.figure1);
            app.degLabel_2.Text = 'deg';
            app.mLabel = uilabel(app.figure1);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.figure1);
            app.mLabel_2.Text = 'm';
            app.errorYaw = uieditfield(app.figure1, 'numeric');
            app.errorYaw.ValueDisplayFormat = '%.2f';
            app.errorX = uieditfield(app.figure1, 'numeric');
            app.errorX.ValueDisplayFormat = '%.2f';
            app.errorY = uieditfield(app.figure1, 'numeric');
            app.errorY.ValueDisplayFormat = '%.2f';
            app.mLabel_3 = uilabel(app.figure1);
            app.mLabel_3.Text = 'm';
            app.lengthSlot = uieditfield(app.figure1, 'numeric');
            app.lengthSlot.ValueDisplayFormat = '%.2f';
            app.timer = uieditfield(app.figure1, 'numeric');
            app.timer.ValueDisplayFormat = '%.2f';
            app.sLabel = uilabel(app.figure1);
            app.sLabel.Text = 's';
            app.indRec = uilamp(app.figure1);
            app.distance = uieditfield(app.figure1, 'numeric');
            app.distance.ValueDisplayFormat = '%.2f';
            app.mLabel_4 = uilabel(app.figure1);
            app.mLabel_4.Text = 'm';
            app.indDistance = uilamp(app.figure1);
            app.name = uieditfield(app.figure1, 'text');
            app.name.Value = '佚名';
            app.Label_25 = uilabel(app.figure1);
            app.Label_25.Text = '身份';
            app.machine = uibutton(app.figure1, 'state');
            app.machine.ValueChangedFcn = createCallbackFcn(app, @machineValueChanged, true);
            app.machine.Text = '机器';
            app.machine.Value = true;
            app.notice = uitextarea(app.figure1);
            app.notice.Visible = 'off';
            app.set = uibutton(app.figure1, 'push');
            app.set.ButtonPushedFcn = createCallbackFcn(app, @setButtonPushed, true);
            app.set.Text = '配置';
            app.reset = uibutton(app.figure1, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = '重置';
            app.exit = uibutton(app.figure1, 'push');
            app.exit.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exit.Text = '退出';
            app.huTimeScore = uieditfield(app.figure1, 'numeric');
            app.huTimeScore.ValueDisplayFormat = '%5.2f';
            app.huAccScore = uieditfield(app.figure1, 'numeric');
            app.huAccScore.ValueDisplayFormat = '%5.2f';
            app.huSafeScore = uieditfield(app.figure1, 'numeric');
            app.huSafeScore.ValueDisplayFormat = '%5.2f';
            app.huComScore = uieditfield(app.figure1, 'numeric');
            app.huComScore.ValueDisplayFormat = '%5.2f';
            app.huRotScore = uieditfield(app.figure1, 'numeric');
            app.huRotScore.ValueDisplayFormat = '%5.2f';
            app.huScore = uieditfield(app.figure1, 'numeric');
            app.huScore.ValueDisplayFormat = '%5.2f';
            app.maTimeScore = uieditfield(app.figure1, 'numeric');
            app.maTimeScore.ValueDisplayFormat = '%5.2f';
            app.maAccScore = uieditfield(app.figure1, 'numeric');
            app.maAccScore.ValueDisplayFormat = '%5.2f';
            app.maSafeScore = uieditfield(app.figure1, 'numeric');
            app.maSafeScore.ValueDisplayFormat = '%5.2f';
            app.maComScore = uieditfield(app.figure1, 'numeric');
            app.maComScore.ValueDisplayFormat = '%5.2f';
            app.maRotScore = uieditfield(app.figure1, 'numeric');
            app.maRotScore.ValueDisplayFormat = '%5.2f';
            app.maScore = uieditfield(app.figure1, 'numeric');
            app.maScore.ValueDisplayFormat = '%5.2f';
            app.pkResult = uilabel(app.figure1);
            app.pkResult.Visible = 'off';
            app.pkResult.Text = '机器机器';
            app.crash = uieditfield(app.figure1, 'text');
            app.crash.Visible = 'off';
            app.crash.Value = '碰撞';
            app.StartButton = uibutton(app.figure1, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Visible = 'off';
            app.StartButton.Text = 'Start';
            app.TabGroup = uitabgroup(app.figure1);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '泊车影像';
            app.trajectory = uiaxes(app.Tab);
            app.Image7 = uiimage(app.figure1);
            app.Image7.ImageSource = 'bar.png';
            app.pkResult_2 = uilabel(app.figure1);
            app.pkResult_2.Text = '人类';
            app.pkResult_3 = uilabel(app.figure1);
            app.pkResult_3.Text = '机器';
            app.Label_26 = uilabel(app.figure1);
            app.Label_26.Text = '泊车时间分';
            app.vsLabel = uilabel(app.figure1);
            app.vsLabel.Text = 'vs';
            app.vsLabel_2 = uilabel(app.figure1);
            app.vsLabel_2.Text = 'vs';
            app.Label_27 = uilabel(app.figure1);
            app.Label_27.Text = '精准度分';
            app.Label_28 = uilabel(app.figure1);
            app.Label_28.Text = '安全度分';
            app.vsLabel_3 = uilabel(app.figure1);
            app.vsLabel_3.Text = 'vs';
            app.vsLabel_4 = uilabel(app.figure1);
            app.vsLabel_4.Text = 'vs';
            app.Label_29 = uilabel(app.figure1);
            app.Label_29.Text = '舒适度分';
            app.vsLabel_5 = uilabel(app.figure1);
            app.vsLabel_5.Text = 'vs';
            app.Label_30 = uilabel(app.figure1);
            app.Label_30.Text = '原地转向分';
            app.vsLabel_6 = uilabel(app.figure1);
            app.vsLabel_6.Text = 'vs';
            app.Label_31 = uilabel(app.figure1);
            app.Label_31.Text = '总分';
            app.Image7_2 = uiimage(app.figure1);
            app.Image7_2.ImageSource = 'bar.png';
            app.mLabel_5 = uilabel(app.figure1);
            app.mLabel_5.Text = 'm';
            app.minDistance = uieditfield(app.figure1, 'numeric');
            app.minDistance.ValueDisplayFormat = '%.2f';
            app.Label_32 = uilabel(app.figure1);
            app.Label_32.Text = '全过程到障碍物最小距离';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parking_GUI_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)parking_GUI_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end